classdef TGRui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SettingTab                    matlab.ui.container.Tab
        testpubButton                 matlab.ui.control.Button
        TargetpartEditField           matlab.ui.control.NumericEditField
        TargetpartEditFieldLabel      matlab.ui.control.Label
        Close3Lamp                    matlab.ui.control.Lamp
        Close3LampLabel               matlab.ui.control.Label
        Close2Lamp                    matlab.ui.control.Lamp
        Close2LampLabel               matlab.ui.control.Label
        Close1Lamp                    matlab.ui.control.Lamp
        Close1LampLabel               matlab.ui.control.Label
        Part3EditField                matlab.ui.control.EditField
        Part3EditFieldLabel           matlab.ui.control.Label
        Part2EditField                matlab.ui.control.EditField
        Part2EditFieldLabel           matlab.ui.control.Label
        Part1EditField                matlab.ui.control.EditField
        Part1Label                    matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        HOMEButton                    matlab.ui.control.Button
        MQTTTab                       matlab.ui.container.Tab
        GrippercommandEditField       matlab.ui.control.NumericEditField
        GrippercommandEditFieldLabel  matlab.ui.control.Label
        dZEditField                   matlab.ui.control.NumericEditField
        dZEditFieldLabel              matlab.ui.control.Label
        dYEditField                   matlab.ui.control.NumericEditField
        dYEditFieldLabel              matlab.ui.control.Label
        dXEditField                   matlab.ui.control.NumericEditField
        dXEditFieldLabel              matlab.ui.control.Label
        ControlTab                    matlab.ui.container.Tab
        LimitationLamp                matlab.ui.control.Lamp
        LimitationLampLabel           matlab.ui.control.Label
        errorzEditField               matlab.ui.control.NumericEditField
        errorzEditFieldLabel          matlab.ui.control.Label
        erroryEditField               matlab.ui.control.NumericEditField
        erroryEditFieldLabel          matlab.ui.control.Label
        errorxEditField               matlab.ui.control.NumericEditField
        errorxEditFieldLabel          matlab.ui.control.Label
        ZButton_Neg                   matlab.ui.control.Button
        ZButton_Pos                   matlab.ui.control.Button
        RobotZposEditField            matlab.ui.control.NumericEditField
        RobotZposEditFieldLabel       matlab.ui.control.Label
        RobotYposEditField            matlab.ui.control.NumericEditField
        RobotYposEditFieldLabel       matlab.ui.control.Label
        RobotXposEditField            matlab.ui.control.NumericEditField
        RobotXposEditFieldLabel       matlab.ui.control.Label
        YButton_Neg                   matlab.ui.control.Button
        YButton_Pos                   matlab.ui.control.Button
        XButton_Pos                   matlab.ui.control.Button
        XButton_Neg                   matlab.ui.control.Button
        MQTTPanel                     matlab.ui.container.Panel
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        MQTTbrokerEditField           matlab.ui.control.EditField
        MQTTbrokerEditFieldLabel      matlab.ui.control.Label
        banner_bottom                 matlab.ui.control.Image
        banner_top                    matlab.ui.control.Image
    end

    
    properties (Access = private) %ppt
    mqttClient      % MQTT client
          %mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        part1Closed     % Closed to part 1
        part2Closed     % Closed to part 2
        part3Closed     % Closed to part 3

        port = 1883
        username = "tesa"
        password = "1234"
        BrokerAddress = "tcp://172.20.18.163"
        BrokerTopicSub = "demo"
        BrokerTopicPub = "demo"
        data 
        %grippercmd
        result

        xshow
        yshow
        zshow
        coshow

        new_task_states
        err
        prex
        prey
        prez

        newx
        newy
        newz
    end
    
    methods (Access = private)
        
        function handleMQTTmsg(app,topic,data)
            disp(topic);
            app.result= jsondecode(data);            
           
                app.dXEditField.Value = app.result.px;
                app.dYEditField.Value = app.result.py;
                app.dZEditField.Value = app.result.pz;
                app.GrippercommandEditField.Value = app.result.gp;
                app.moveJoints(app.result.px, app.result.py, app.result.py)
           
                if app.result.gp == "1"
                    if app.part1Closed
                        app.robotCell.PartOnRobot = 1;
                        app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                        write(app.mqttClient, app.BrokerTopicPub, '{"grasp":"ok"}')
                    else
                        write(app.mqttClient,  app.BrokerTopicPub, '{"grasp":"fail"}')
                    end
                
                else
                 disp("you are not picking")
                end


        end
        
        function moveJoints(app, dX, dY, dZ)
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            app.prex = app.robotCell.CurrentTaskConfig(1,4);
            app.prey = app.robotCell.CurrentTaskConfig(2,4);
            app.prez = app.robotCell.CurrentTaskConfig(3,4);
            app.new_task_states = trvec2tform([X+dX, Y+dY, Z+dZ])*axang2tform([0 1 0 pi]);
            
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.new_task_states, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
            app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4);
            app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4);
            app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4);
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            app.newx = app.robotCell.CurrentTaskConfig(1,4);
            app.newy = app.robotCell.CurrentTaskConfig(2,4);
            app.newz = app.robotCell.CurrentTaskConfig(3,4);

            app.errorxEditField.Value = dX-(app.newx-app.prex);
            app.erroryEditField.Value = dY-(app.newy-app.prey);
            app.errorzEditField.Value = dZ-(app.newz-app.prez);

            app.xshow = num2str(app.robotCell.CurrentTaskConfig(1,4)); %TODO
            app.yshow = num2str(app.robotCell.CurrentTaskConfig(2,4));
            app.zshow = num2str(app.robotCell.CurrentTaskConfig(3,4));
            %app.coshow =
            %jsonencode(containers.Map({'x','y','z'},[app.xshow,app.yshow,app.zshow]));
            %%edit
            %write(app.mqttClient, app.BrokerTopicPub, app.coshow);


            part1 = str2num(app.Part1EditField.Value) + [0,0,0.05];
            part2 = str2num(app.Part2EditField.Value) + [0,0,0.05];
            part3 = str2num(app.Part3EditField.Value) + [0,0,0.05];
            if norm(part1 - [X,Y,Z]) < 0.05
                app.part1Closed = 1;
                app.Close1Lamp.Color = 'g';
                if app.TargetpartEditField.Value == 1
                    write(app.mqttClient, app.BrokerTopicPub, "600")
                 else 
                    write(app.mqttClient, app.BrokerTopicPub, "not this part")         
                end
            else 
                app.part1Closed = 0;
                app.Close1Lamp.Color = 'r';
            end
            if norm(part2 - [X,Y,Z]) < 0.05
                app.part2Closed = 1;
                app.Close2Lamp.Color = 'g';
                if app.TargetpartEditField.Value == 2
                    write(app.mqttClient, app.BrokerTopicPub, "600")
                else 
                    write(app.mqttClient, app.BrokerTopicPub, "not this part")  
                end
            else 
                app.part2Closed = 0;
                app.Close2Lamp.Color = 'r';
            end
            if norm(part3 - [X,Y,Z]) < 0.05
                app.part3Closed = 1;
                app.Close3Lamp.Color = 'g';
                if app.TargetpartEditField.Value == 3
                    write(app.mqttClient, app.BrokerTopicPub, "600")
                 else 
                    write(app.mqttClient, app.BrokerTopicPub, "not this part")          
                end
            else 
                app.part3Closed = 0;
                app.Close3Lamp.Color = 'r';
            end                        
        end


    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.StatusLamp.Color = 'r';
            app.MQTTbrokerEditField.Value = "tcp://172.20.18.163";
            % initialize robot setting
            app.Part1EditField.Value = "[0.4 -0.1 0.26]";
            app.Part2EditField.Value = "[0.5 -0.15 0.26]"; 
            app.Part3EditField.Value = "[0.52 0 0.26]";
            app.HOMEButton.Enable = "off";
            % initialize status
            app.part1Closed = 0;
            app.part2Closed = 0;
            app.part3Closed = 0;
            app.Close1Lamp.Color = [0, 0.2, 0];
            app.Close2Lamp.Color = [0, 0.2, 0];
            app.Close3Lamp.Color = [0, 0.2, 0];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.mqttClient = mqttclient(app.MQTTbrokerEditField.Value,Port = app.port, Username = app.username,Password = app.password);
            if app.mqttClient.Connected
                app.StatusLamp.Color = 'g';
                subscribe(app.mqttClient, app.BrokerTopicSub,Callback=@app.handleMQTTmsg);
            end
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
            app.RobotXposEditField.Value = 0.4;
            app.RobotYposEditField.Value = 0;
            app.RobotZposEditField.Value = 0.5;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = str2num(app.Part1EditField.Value);
            part2 = str2num(app.Part2EditField.Value);
            part3 = str2num(app.Part3EditField.Value);
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                app.HOMEButton.Enable = "on";
            catch
                disp("Error from part setting");
            end
        end

        % Button pushed function: XButton_Pos
        function XButton_PosPushed(app, event)
            app.moveJoints(0.05, 0, 0)
        end

        % Button pushed function: XButton_Neg
        function XButton_NegPushed(app, event)
            app.moveJoints(-0.05, 0, 0)
        end

        % Button pushed function: YButton_Pos
        function YButton_PosPushed(app, event)
            app.moveJoints(0, 0.05, 0)
        end

        % Button pushed function: YButton_Neg
        function YButton_NegPushed(app, event)
            app.moveJoints(0, -0.05, 0)
        end

        % Button pushed function: ZButton_Pos
        function ZButton_PosPushed(app, event)
            app.moveJoints(0, 0, 0.05)
        end

        % Button pushed function: ZButton_Neg
        function ZButton_NegPushed(app, event)
            app.moveJoints(0, 0, -0.05)
        end

        % Button pushed function: testpubButton
        function testpubButtonPushed(app, event)
            write(app.mqttClient,app.BrokerTopicPub, "10")
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.banner_top = uiimage(app.UIFigure);
            app.banner_top.ImageSource = 'banner_top.jpg';
            app.banner_bottom = uiimage(app.UIFigure);
            app.banner_bottom.ImageSource = fullfile(pathToMLAPP, 'banner_bottom.jpg');
            app.MQTTPanel = uipanel(app.UIFigure);
            app.MQTTPanel.Title = 'MQTT';
            app.MQTTbrokerEditFieldLabel = uilabel(app.MQTTPanel);
            app.MQTTbrokerEditFieldLabel.Text = 'MQTT broker';
            app.MQTTbrokerEditField = uieditfield(app.MQTTPanel, 'text');
            app.ConnectButton = uibutton(app.MQTTPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.MQTTPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.MQTTPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.HOMEButton = uibutton(app.SettingTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.STARTButton = uibutton(app.SettingTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Part1Label = uilabel(app.SettingTab);
            app.Part1Label.Text = 'Part 1';
            app.Part1EditField = uieditfield(app.SettingTab, 'text');
            app.Part1EditField.Value = '[0.4 -0.1 0.26]';
            app.Part2EditFieldLabel = uilabel(app.SettingTab);
            app.Part2EditFieldLabel.Text = 'Part 2';
            app.Part2EditField = uieditfield(app.SettingTab, 'text');
            app.Part2EditField.Value = '[0.5 -0.15 0.26]';
            app.Part3EditFieldLabel = uilabel(app.SettingTab);
            app.Part3EditFieldLabel.Text = 'Part 3';
            app.Part3EditField = uieditfield(app.SettingTab, 'text');
            app.Part3EditField.Value = '[0.52 0 0.26]';
            app.Close1LampLabel = uilabel(app.SettingTab);
            app.Close1LampLabel.Text = 'Close 1';
            app.Close1Lamp = uilamp(app.SettingTab);
            app.Close2LampLabel = uilabel(app.SettingTab);
            app.Close2LampLabel.Text = 'Close 2';
            app.Close2Lamp = uilamp(app.SettingTab);
            app.Close3LampLabel = uilabel(app.SettingTab);
            app.Close3LampLabel.Text = 'Close 3';
            app.Close3Lamp = uilamp(app.SettingTab);
            app.TargetpartEditFieldLabel = uilabel(app.SettingTab);
            app.TargetpartEditFieldLabel.Text = 'Target part';
            app.TargetpartEditField = uieditfield(app.SettingTab, 'numeric');
            app.testpubButton = uibutton(app.SettingTab, 'push');
            app.testpubButton.ButtonPushedFcn = createCallbackFcn(app, @testpubButtonPushed, true);
            app.testpubButton.Text = 'testpub';
            app.MQTTTab = uitab(app.TabGroup);
            app.MQTTTab.Title = 'MQTT';
            app.dXEditFieldLabel = uilabel(app.MQTTTab);
            app.dXEditFieldLabel.Text = 'dX';
            app.dXEditField = uieditfield(app.MQTTTab, 'numeric');
            app.dYEditFieldLabel = uilabel(app.MQTTTab);
            app.dYEditFieldLabel.Text = 'dY';
            app.dYEditField = uieditfield(app.MQTTTab, 'numeric');
            app.dZEditFieldLabel = uilabel(app.MQTTTab);
            app.dZEditFieldLabel.Text = 'dZ';
            app.dZEditField = uieditfield(app.MQTTTab, 'numeric');
            app.GrippercommandEditFieldLabel = uilabel(app.MQTTTab);
            app.GrippercommandEditFieldLabel.Text = 'Gripper command';
            app.GrippercommandEditField = uieditfield(app.MQTTTab, 'numeric');
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.XButton_Neg = uibutton(app.ControlTab, 'push');
            app.XButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @XButton_NegPushed, true);
            app.XButton_Neg.Text = 'X-';
            app.XButton_Pos = uibutton(app.ControlTab, 'push');
            app.XButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @XButton_PosPushed, true);
            app.XButton_Pos.Text = 'X+';
            app.YButton_Pos = uibutton(app.ControlTab, 'push');
            app.YButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @YButton_PosPushed, true);
            app.YButton_Pos.Text = 'Y+';
            app.YButton_Neg = uibutton(app.ControlTab, 'push');
            app.YButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @YButton_NegPushed, true);
            app.YButton_Neg.Text = 'Y-';
            app.RobotXposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotXposEditFieldLabel.Text = 'Robot X pos';
            app.RobotXposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotYposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotYposEditFieldLabel.Text = 'Robot Y pos';
            app.RobotYposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotZposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotZposEditFieldLabel.Text = 'Robot Z pos';
            app.RobotZposEditField = uieditfield(app.ControlTab, 'numeric');
            app.ZButton_Pos = uibutton(app.ControlTab, 'push');
            app.ZButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PosPushed, true);
            app.ZButton_Pos.Text = 'Z+';
            app.ZButton_Neg = uibutton(app.ControlTab, 'push');
            app.ZButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @ZButton_NegPushed, true);
            app.ZButton_Neg.Text = 'Z-';
            app.errorxEditFieldLabel = uilabel(app.ControlTab);
            app.errorxEditFieldLabel.Text = 'error x';
            app.errorxEditField = uieditfield(app.ControlTab, 'numeric');
            app.erroryEditFieldLabel = uilabel(app.ControlTab);
            app.erroryEditFieldLabel.Text = 'error y';
            app.erroryEditField = uieditfield(app.ControlTab, 'numeric');
            app.errorzEditFieldLabel = uilabel(app.ControlTab);
            app.errorzEditFieldLabel.Text = 'error z';
            app.errorzEditField = uieditfield(app.ControlTab, 'numeric');
            app.LimitationLampLabel = uilabel(app.ControlTab);
            app.LimitationLampLabel.Text = 'Limitation ';
            app.LimitationLamp = uilamp(app.ControlTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TGRui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end