classdef TGRui_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingTab                      matlab.ui.container.Tab
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLamp_3Label               matlab.ui.control.Label
        StatusLamp_2                    matlab.ui.control.Lamp
        StatusLamp_2Label               matlab.ui.control.Label
        PubButton                       matlab.ui.control.Button
        SubButton                       matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        ProxyOutLabel                   matlab.ui.control.Label
        ProxyInLabel                    matlab.ui.control.Label
        TopicrosoutEditField            matlab.ui.control.EditField
        TopicROSEditField_2Label        matlab.ui.control.Label
        TopicrosinEditField             matlab.ui.control.EditField
        TopicROSEditFieldLabel          matlab.ui.control.Label
        NodeEditField                   matlab.ui.control.EditField
        NodeEditFieldLabel              matlab.ui.control.Label
        RobotSetupLabel                 matlab.ui.control.Label
        ROSConnectionLabel              matlab.ui.control.Label
        MQTTPubtopicEditField           matlab.ui.control.EditField
        MQTTPubtopicEditFieldLabel      matlab.ui.control.Label
        MQTTSubtopicEditField           matlab.ui.control.EditField
        MQTTSubtopicEditFieldLabel      matlab.ui.control.Label
        NodelistDropDown                matlab.ui.control.DropDown
        NodelistDropDownLabel           matlab.ui.control.Label
        addnode                         matlab.ui.control.Button
        MQTTConnectionLabel             matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        MQTTbrokerEditField             matlab.ui.control.EditField
        MQTTbrokerEditFieldLabel        matlab.ui.control.Label
        testpubButton                   matlab.ui.control.Button
        TargetpartEditField             matlab.ui.control.NumericEditField
        TargetpartEditFieldLabel        matlab.ui.control.Label
        Close3Lamp                      matlab.ui.control.Lamp
        Close3LampLabel                 matlab.ui.control.Label
        Close2Lamp                      matlab.ui.control.Lamp
        Close2LampLabel                 matlab.ui.control.Label
        Close1Lamp                      matlab.ui.control.Lamp
        Close1LampLabel                 matlab.ui.control.Label
        Part3EditField                  matlab.ui.control.EditField
        Part3EditFieldLabel             matlab.ui.control.Label
        Part2EditField                  matlab.ui.control.EditField
        Part2EditFieldLabel             matlab.ui.control.Label
        Part1EditField                  matlab.ui.control.EditField
        Part1Label                      matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        HOMEButton                      matlab.ui.control.Button
        INPUTTab                        matlab.ui.container.Tab
        RosinputEditField               matlab.ui.control.EditField
        RosinputEditFieldLabel          matlab.ui.control.Label
        ROSInputLabel                   matlab.ui.control.Label
        GrippercommandEditField_2       matlab.ui.control.NumericEditField
        GrippercommandEditField_2Label  matlab.ui.control.Label
        dZEditField_2                   matlab.ui.control.NumericEditField
        dZEditField_2Label              matlab.ui.control.Label
        dYEditField_2                   matlab.ui.control.NumericEditField
        dYEditField_2Label              matlab.ui.control.Label
        dXEditField_2                   matlab.ui.control.NumericEditField
        dXEditField_2Label              matlab.ui.control.Label
        MQTTInputLabel                  matlab.ui.control.Label
        LimitationLamp                  matlab.ui.control.Lamp
        LimitationLampLabel             matlab.ui.control.Label
        errorzEditField                 matlab.ui.control.NumericEditField
        errorzEditFieldLabel            matlab.ui.control.Label
        erroryEditField                 matlab.ui.control.NumericEditField
        erroryEditFieldLabel            matlab.ui.control.Label
        errorxEditField                 matlab.ui.control.NumericEditField
        errorxEditFieldLabel            matlab.ui.control.Label
        RobotZposEditField              matlab.ui.control.NumericEditField
        RobotZposEditFieldLabel         matlab.ui.control.Label
        RobotYposEditField              matlab.ui.control.NumericEditField
        RobotYposEditFieldLabel         matlab.ui.control.Label
        RobotXposEditField              matlab.ui.control.NumericEditField
        RobotXposEditFieldLabel         matlab.ui.control.Label
        ZButton_Neg                     matlab.ui.control.Button
        ZButton_Pos                     matlab.ui.control.Button
        YButton_Neg                     matlab.ui.control.Button
        YButton_Pos                     matlab.ui.control.Button
        XButton_Pos                     matlab.ui.control.Button
        XButton_Neg                     matlab.ui.control.Button
        GrippercommandEditField         matlab.ui.control.NumericEditField
        GrippercommandEditFieldLabel    matlab.ui.control.Label
        dZEditField                     matlab.ui.control.NumericEditField
        dZEditFieldLabel                matlab.ui.control.Label
        dYEditField                     matlab.ui.control.NumericEditField
        dYEditFieldLabel                matlab.ui.control.Label
        dXEditField                     matlab.ui.control.NumericEditField
        dXEditFieldLabel                matlab.ui.control.Label
        banner_bottom                   matlab.ui.control.Image
        banner_top                      matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
    end

    
    properties (Access = private) %ppt
    mqttClient      % MQTT client
          %mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        part1Closed     % Closed to part 1
        part2Closed     % Closed to part 2
        part3Closed     % Closed to part 3

        port = 1883
        username = "tesa"
        password = "1234"
        %BrokerAddress = "tcp://172.20.18.163"
        %BrokerAddress = "tcp://10.13.8.163"
        BrokerAddress = "tcp://broker.hivemq.com"
        BrokerTopicSub ;
        BrokerTopicPub;
        Brokerpub2 = "position";
        data 
        %grippercmd
        result

        xshow
        yshow
        zshow
        coshow

        new_task_states
        err
        prex
        prey
        prez

        newx
        newy
        newz

        %ROS
        cliNode %ROS node
        proxyIn
        rosSub
        rosReceive
        rosPub
        proxyOut
        rosmsg
        gVar
        js
        rosReceived
       
    end
    
    methods (Access = private)
        
        function handleMQTTmsg(app,topic,data)
            disp(topic);
            app.result= jsondecode(data);     %problem       
           
                app.dXEditField.Value = app.result.px;
                app.dYEditField.Value = app.result.py;
                app.dZEditField.Value = app.result.pz;
                app.GrippercommandEditField.Value = app.result.gp;
                app.moveJoints(app.result.px, app.result.py, app.result.py)
           
                if app.result.gp == "1"
                    if app.part1Closed || app.part2Closed || app.part3Closed 
                        app.robotCell.PartOnRobot = 1;
                        app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                        write(app.mqttClient, app.MQTTPubtopicEditField.Value, '100 100')
                    else

                        disp("not gripping")
                    end
                
                else
                    app.release();
                
                end
        end


        function moveJoints(app, dX, dY, dZ)
            %IK setup
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            app.prex = app.robotCell.CurrentTaskConfig(1,4);
            app.prey = app.robotCell.CurrentTaskConfig(2,4);
            app.prez = app.robotCell.CurrentTaskConfig(3,4);
            app.new_task_states = trvec2tform([X+dX, Y+dY, Z+dZ])*axang2tform([0 1 0 pi]);
            %IK
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.new_task_states, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
            app.RobotXposEditField.Value = app.robotCell.CurrentTaskConfig(1,4);
            app.RobotYposEditField.Value = app.robotCell.CurrentTaskConfig(2,4);
            app.RobotZposEditField.Value = app.robotCell.CurrentTaskConfig(3,4);
            X = app.robotCell.CurrentTaskConfig(1,4);
            Y = app.robotCell.CurrentTaskConfig(2,4);
            Z = app.robotCell.CurrentTaskConfig(3,4);
            %set new value
            app.newx = app.robotCell.CurrentTaskConfig(1,4);
            app.newy = app.robotCell.CurrentTaskConfig(2,4);
            app.newz = app.robotCell.CurrentTaskConfig(3,4);
            %calculate error of coordination
            app.errorxEditField.Value = abs(dX-(app.newx-app.prex));
            app.erroryEditField.Value = abs(dY-(app.newy-app.prey));
            app.errorzEditField.Value = abs(dZ-(app.newz-app.prez));
            app.limitation();
            %prepare json
            app.js.x = app.RobotXposEditField.Value;
            app.js.y = app.RobotYposEditField.Value;
            app.js.z = app.RobotZposEditField.Value;
            app.coshow = jsonencode(app.js,"PrettyPrint",true);
            write(app.mqttClient, "position", app.coshow);

            part1 = str2num(app.Part1EditField.Value) + [0,0,0.05];
            part2 = str2num(app.Part2EditField.Value) + [0,0,0.05];
            part3 = str2num(app.Part3EditField.Value) + [0,0,0.05];
            if norm(part1 - [X,Y,Z]) < 0.05
                app.part1Closed = 1;
                app.Close1Lamp.Color = 'g';
                if app.TargetpartEditField.Value == 1 && norm(part1 - [X,Y,Z]) < 0.08
                    app.robotCell.HomeRobotTaskConfig = trvec2tform(part1)*axang2tform([0 1 0 pi]);
                    app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
                    weights = ones(1, 6);
                    init_pose = app.robotCell.CurrentJointConfig;
                    joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
                    app.robotCell.moveJoints(joint_sols);
                    app.moveJoints(joint_sols);%todo
                    write(app.mqttClient, app.MQTTPubtopicEditField.Value, "300 300")
                
                else 
                    write(app.mqttClient, app.MQTTPubtopicEditField.Value, "not this part") 
                end
            
            else 
                app.part1Closed = 0;
                app.Close1Lamp.Color = 'r';
            
            end
            %%%%%%%%%%%%%%%%%%%%
            if norm(part2 - [X,Y,Z]) < 0.05
                app.part2Closed = 1;
                app.Close2Lamp.Color = 'g';
                if app.TargetpartEditField.Value == 2
                    app.robotCell.HomeRobotTaskConfig = trvec2tform(part2)*axang2tform([0 1 0 pi]);
                    app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
                    weights = ones(1, 6);
                    init_pose = app.robotCell.CurrentJointConfig;
                    joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
                    app.robotCell.moveJoints(joint_sols);
                    app.moveJoints(joint_sols);%todo
                    write(app.mqttClient, app.MQTTPubtopicEditField.Value, "300 300")
                else 
                    write(app.mqttClient, app.MQTTPubtopicEditField.Value, "not this part")  
                end
            else 
                app.part2Closed = 0;
                app.Close2Lamp.Color = 'r';
            end
            if norm(part3 - [X,Y,Z]) < 0.05
                app.part3Closed = 1;
                app.Close3Lamp.Color = 'g';
                if app.TargetpartEditField.Value == 3
                    app.robotCell.HomeRobotTaskConfig = trvec2tform(part3)*axang2tform([0 1 0 pi]);
                    app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
                    weights = ones(1, 6);
                    init_pose = app.robotCell.CurrentJointConfig;
                    joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
                    app.robotCell.moveJoints(joint_sols);
                    app.moveJoints(joint_sols);%todo
                    write(app.mqttClient, app.MQTTPubtopicEditField.Value, "300 300")
                 else 
                    write(app.mqttClient, app.MQTTPubtopicEditField.Value, "not this part")          
                end
            else 
                app.part3Closed = 0;
                app.Close3Lamp.Color = 'r';
            end                        
        end


        function limitation(app) %check %cannot stop
            if app.errorxEditField.Value> 0.02 || app.erroryEditField.Value >0.02 || app.errorzEditField.Value>0.02
                app.LimitationLamp.Color = 'r';
                app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);

            else
                app.LimitationLamp.Color = 'g';
            end
            
        end
        
        function showrosmsg(app)
            disp(msg)
            app.gVar = msg;
        end
     
        
        function release(app)
            if app.robotCell.PartOnRobot ~= 0 %release 
                 app.robotCell.Environment.Parts{app.robotCell.PartOnRobot}.mesh.Pose = app.robotCell.CurrentTaskConfig * trvec2tform([0 0 0.06]);
                 app.robotCell.Environment.Parts{app.robotCell.PartOnRobot}.plotHandle.Matrix = app.robotCell.Environment.Parts{app.robotCell.PartOnRobot}.mesh.Pose;
                 app.robotCell.PartOnRobot = 0;
                 app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
                 write(app.mqttClient, app.MQTTPubtopicEditField.Value, "600 600")
            else
                disp('holding obj')
            end
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.StatusLamp.Color = 'r';
            app.MQTTbrokerEditField.Value = "tcp://broker.hivemq.com"; %check
            app.port = 1883;
            app.PasswordEditField.Value = "1234";
            app.UsernameEditField.Value = "tesa";
            app.MQTTSubtopicEditField.Value ="TonyA";
            app.MQTTPubtopicEditField.Value = "PWM";
            % initialize robot setting
            app.Part1EditField.Value = "[0.4 -0.1 0.26]";
            app.Part2EditField.Value = "[0.5 -0.15 0.26]"; 
            app.Part3EditField.Value = "[0.52 0 0.26]";
            app.HOMEButton.Enable = "off";
            % initialize status
            app.part1Closed = 0;
            app.part2Closed = 0;
            app.part3Closed = 0;
            app.Close1Lamp.Color = [0, 0.2, 0];
            app.Close2Lamp.Color = [0, 0.2, 0];
            app.Close3Lamp.Color = [0, 0.2, 0];
         
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
             app.mqttClient = mqttclient(app.MQTTbrokerEditField.Value,Port = app.port, Username = app.UsernameEditField.Value,Password = app.PasswordEditField.Value);
            if app.mqttClient.Connected
                app.StatusLamp.Color = 'g';
                app.data = subscribe(app.mqttClient, app.MQTTSubtopicEditField.Value,Callback=@app.handleMQTTmsg);

                app.result= jsondecode(app.data); %warning           
           
                app.dXEditField.Value = app.result.px;
                app.dYEditField.Value = app.result.py;
                app.dZEditField.Value = app.result.pz;
                app.GrippercommandEditField.Value = app.result.gp;
            end
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
            app.RobotXposEditField.Value = 0.4;
            app.RobotYposEditField.Value = 0;
            app.RobotZposEditField.Value = 0.5;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = str2num(app.Part1EditField.Value);
            part2 = str2num(app.Part2EditField.Value);
            part3 = str2num(app.Part3EditField.Value);
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                app.HOMEButton.Enable = "on";
            catch
                disp("Error from part setting");
            end
        end

        % Button pushed function: XButton_Pos
        function XButton_PosPushed(app, event)
            app.moveJoints(0.05, 0, 0)
        end

        % Button pushed function: XButton_Neg
        function XButton_NegPushed(app, event)
            app.moveJoints(-0.05, 0, 0)
        end

        % Button pushed function: YButton_Pos
        function YButton_PosPushed(app, event)
            app.moveJoints(0, 0.05, 0)
        end

        % Button pushed function: YButton_Neg
        function YButton_NegPushed(app, event)
            app.moveJoints(0, -0.05, 0)
        end

        % Button pushed function: ZButton_Pos
        function ZButton_PosPushed(app, event)
            app.moveJoints(0, 0, 0.05)
        end

        % Button pushed function: ZButton_Neg
        function ZButton_NegPushed(app, event)
            app.moveJoints(0, 0, -0.05)
        end

        % Button pushed function: testpubButton
        function testpubButtonPushed(app, event)
            write(app.mqttClient,app.MQTTPubtopicEditField.Value, "10")
            
        end

        % Button pushed function: SubButton
        function SubButtonPushed(app, event)
          app.proxyIn = mqtt2ros("TonyA","TonyA"); %co           
            app.cliNode = ros2node("Clinode"); %create node %please check
            app.rosSub = ros2subscriber(app.cliNode,"TonyA",@showrosmsg); %ros subscribe 
            app.StatusLamp_2.Color = 'y';
            app.rosReceive = receive(app.rosSub);
            app.StatusLamp_2.Color = 'g';
            app.RosinputEditField.Value = app.rosReceive.data;
%             
%             app.rosReceived = jsondecode(app.RosinputEditField.Value);
%            app.dXEditField_2 = sprintf('%.6f',app.rosReceived.px) ;
%             app.dYEditField_2 = app.rosReceived.py ;
%             app.dZEditField_2 = app.rosReceived.pz ;
           
%                 if app.app.rosReceive.data.gp == "1"
%                     if app.part1Closed || app.part2Closed || app.part3Closed 
%                         app.robotCell.PartOnRobot = 1;
%                         app.robotCell.moveJoints(app.robotCell.CurrentJointConfig);
%                         write(app.mqttClient, app.MQTTPubtopicEditField.Value, '100 100')
%                     else
%                         write(app.mqttClient,  app.MQTTPubtopicEditField.Value, '{"grasp":"fail"}')
%                     end
%                 else
%                   disp("you are not picking")
%                 end
%             catch
%              app.StatusLamp_2.Color = 'b';
%             end
        end

        % Button pushed function: PubButton
        function PubButtonPushed(app, event)
%            try
%             app.cliNode = ros2node("Clinode"); %create node
%             app.proxyOut = ros2mqtt("tgr2022/bmeranger12/out","tgr2022/bmeranger12/out");
%             app.rosPub = ros2publisher(app.cliNode, "tgr2022/bmeranger12/out","std_msg/String");
%             app.StatusLamp_3.Color = 'y';
%             app.rosmsg = ros2message(app.rosPub);
%             app.rosmsg= 'echo back';
%             send(app.rosPub,app.rosmsg);
%             app.StatusLamp_3.Color = 'g';
%            catch
%                app.StatusLamp_3.Color = 'b';
%            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'blue-futuristic-networking-technology_53876-100679.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'blue-futuristic-networking-technology_53876-100679.jpg');
            app.banner_top = uiimage(app.UIFigure);
            app.banner_top.ImageSource = 'banner_top.jpg';
            app.banner_bottom = uiimage(app.UIFigure);
            app.banner_bottom.ImageSource = fullfile(pathToMLAPP, 'banner_bottom.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.HOMEButton = uibutton(app.SettingTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.STARTButton = uibutton(app.SettingTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Part1Label = uilabel(app.SettingTab);
            app.Part1Label.Text = 'Part 1';
            app.Part1EditField = uieditfield(app.SettingTab, 'text');
            app.Part1EditField.Value = '[0.4 -0.1 0.26]';
            app.Part2EditFieldLabel = uilabel(app.SettingTab);
            app.Part2EditFieldLabel.Text = 'Part 2';
            app.Part2EditField = uieditfield(app.SettingTab, 'text');
            app.Part2EditField.Value = '[0.5 -0.15 0.26]';
            app.Part3EditFieldLabel = uilabel(app.SettingTab);
            app.Part3EditFieldLabel.Text = 'Part 3';
            app.Part3EditField = uieditfield(app.SettingTab, 'text');
            app.Part3EditField.Value = '[0.52 0 0.26]';
            app.Close1LampLabel = uilabel(app.SettingTab);
            app.Close1LampLabel.Text = 'Close 1';
            app.Close1Lamp = uilamp(app.SettingTab);
            app.Close2LampLabel = uilabel(app.SettingTab);
            app.Close2LampLabel.Text = 'Close 2';
            app.Close2Lamp = uilamp(app.SettingTab);
            app.Close3LampLabel = uilabel(app.SettingTab);
            app.Close3LampLabel.Text = 'Close 3';
            app.Close3Lamp = uilamp(app.SettingTab);
            app.TargetpartEditFieldLabel = uilabel(app.SettingTab);
            app.TargetpartEditFieldLabel.Text = 'Target part';
            app.TargetpartEditField = uieditfield(app.SettingTab, 'numeric');
            app.testpubButton = uibutton(app.SettingTab, 'push');
            app.testpubButton.ButtonPushedFcn = createCallbackFcn(app, @testpubButtonPushed, true);
            app.testpubButton.Text = 'testpub';
            app.MQTTbrokerEditFieldLabel = uilabel(app.SettingTab);
            app.MQTTbrokerEditFieldLabel.Text = 'MQTT broker';
            app.MQTTbrokerEditField = uieditfield(app.SettingTab, 'text');
            app.ConnectButton = uibutton(app.SettingTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.SettingTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SettingTab);
            app.MQTTConnectionLabel = uilabel(app.SettingTab);
            app.MQTTConnectionLabel.Text = 'MQTT Connection';
            app.addnode = uibutton(app.SettingTab, 'push');
            app.addnode.Text = '+';
            app.NodelistDropDownLabel = uilabel(app.SettingTab);
            app.NodelistDropDownLabel.Text = 'Node list';
            app.NodelistDropDown = uidropdown(app.SettingTab);
            app.MQTTSubtopicEditFieldLabel = uilabel(app.SettingTab);
            app.MQTTSubtopicEditFieldLabel.Text = 'MQTT Sub topic';
            app.MQTTSubtopicEditField = uieditfield(app.SettingTab, 'text');
            app.MQTTPubtopicEditFieldLabel = uilabel(app.SettingTab);
            app.MQTTPubtopicEditFieldLabel.Text = 'MQTT Pub topic';
            app.MQTTPubtopicEditField = uieditfield(app.SettingTab, 'text');
            app.ROSConnectionLabel = uilabel(app.SettingTab);
            app.ROSConnectionLabel.Text = 'ROS Connection';
            app.RobotSetupLabel = uilabel(app.SettingTab);
            app.RobotSetupLabel.Text = 'Robot Setup';
            app.NodeEditFieldLabel = uilabel(app.SettingTab);
            app.NodeEditFieldLabel.Text = 'Node';
            app.NodeEditField = uieditfield(app.SettingTab, 'text');
            app.TopicROSEditFieldLabel = uilabel(app.SettingTab);
            app.TopicROSEditFieldLabel.Text = 'Topic ROS';
            app.TopicrosinEditField = uieditfield(app.SettingTab, 'text');
            app.TopicROSEditField_2Label = uilabel(app.SettingTab);
            app.TopicROSEditField_2Label.Text = 'Topic ROS';
            app.TopicrosoutEditField = uieditfield(app.SettingTab, 'text');
            app.ProxyInLabel = uilabel(app.SettingTab);
            app.ProxyInLabel.Text = 'Proxy In';
            app.ProxyOutLabel = uilabel(app.SettingTab);
            app.ProxyOutLabel.Text = 'Proxy Out';
            app.UsernameEditFieldLabel = uilabel(app.SettingTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.SettingTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.SettingTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.SettingTab, 'text');
            app.SubButton = uibutton(app.SettingTab, 'push');
            app.SubButton.ButtonPushedFcn = createCallbackFcn(app, @SubButtonPushed, true);
            app.SubButton.Text = 'Sub';
            app.PubButton = uibutton(app.SettingTab, 'push');
            app.PubButton.ButtonPushedFcn = createCallbackFcn(app, @PubButtonPushed, true);
            app.PubButton.Text = 'Pub';
            app.StatusLamp_2Label = uilabel(app.SettingTab);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.SettingTab);
            app.StatusLamp_3Label = uilabel(app.SettingTab);
            app.StatusLamp_3Label.Text = 'Status';
            app.StatusLamp_3 = uilamp(app.SettingTab);
            app.INPUTTab = uitab(app.TabGroup);
            app.INPUTTab.Title = 'INPUT';
            app.dXEditFieldLabel = uilabel(app.INPUTTab);
            app.dXEditFieldLabel.Text = 'dX';
            app.dXEditField = uieditfield(app.INPUTTab, 'numeric');
            app.dYEditFieldLabel = uilabel(app.INPUTTab);
            app.dYEditFieldLabel.Text = 'dY';
            app.dYEditField = uieditfield(app.INPUTTab, 'numeric');
            app.dZEditFieldLabel = uilabel(app.INPUTTab);
            app.dZEditFieldLabel.Text = 'dZ';
            app.dZEditField = uieditfield(app.INPUTTab, 'numeric');
            app.GrippercommandEditFieldLabel = uilabel(app.INPUTTab);
            app.GrippercommandEditFieldLabel.Text = 'Gripper command';
            app.GrippercommandEditField = uieditfield(app.INPUTTab, 'numeric');
            app.XButton_Neg = uibutton(app.INPUTTab, 'push');
            app.XButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @XButton_NegPushed, true);
            app.XButton_Neg.Text = 'X-';
            app.XButton_Pos = uibutton(app.INPUTTab, 'push');
            app.XButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @XButton_PosPushed, true);
            app.XButton_Pos.Text = 'X+';
            app.YButton_Pos = uibutton(app.INPUTTab, 'push');
            app.YButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @YButton_PosPushed, true);
            app.YButton_Pos.Text = 'Y+';
            app.YButton_Neg = uibutton(app.INPUTTab, 'push');
            app.YButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @YButton_NegPushed, true);
            app.YButton_Neg.Text = 'Y-';
            app.ZButton_Pos = uibutton(app.INPUTTab, 'push');
            app.ZButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PosPushed, true);
            app.ZButton_Pos.Text = 'Z+';
            app.ZButton_Neg = uibutton(app.INPUTTab, 'push');
            app.ZButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @ZButton_NegPushed, true);
            app.ZButton_Neg.Text = 'Z-';
            app.RobotXposEditFieldLabel = uilabel(app.INPUTTab);
            app.RobotXposEditFieldLabel.Text = 'Robot X pos';
            app.RobotXposEditField = uieditfield(app.INPUTTab, 'numeric');
            app.RobotYposEditFieldLabel = uilabel(app.INPUTTab);
            app.RobotYposEditFieldLabel.Text = 'Robot Y pos';
            app.RobotYposEditField = uieditfield(app.INPUTTab, 'numeric');
            app.RobotZposEditFieldLabel = uilabel(app.INPUTTab);
            app.RobotZposEditFieldLabel.Text = 'Robot Z pos';
            app.RobotZposEditField = uieditfield(app.INPUTTab, 'numeric');
            app.errorxEditFieldLabel = uilabel(app.INPUTTab);
            app.errorxEditFieldLabel.Text = 'error x';
            app.errorxEditField = uieditfield(app.INPUTTab, 'numeric');
            app.erroryEditFieldLabel = uilabel(app.INPUTTab);
            app.erroryEditFieldLabel.Text = 'error y';
            app.erroryEditField = uieditfield(app.INPUTTab, 'numeric');
            app.errorzEditFieldLabel = uilabel(app.INPUTTab);
            app.errorzEditFieldLabel.Text = 'error z';
            app.errorzEditField = uieditfield(app.INPUTTab, 'numeric');
            app.LimitationLampLabel = uilabel(app.INPUTTab);
            app.LimitationLampLabel.Text = 'Limitation ';
            app.LimitationLamp = uilamp(app.INPUTTab);
            app.MQTTInputLabel = uilabel(app.INPUTTab);
            app.MQTTInputLabel.Text = 'MQTT Input';
            app.dXEditField_2Label = uilabel(app.INPUTTab);
            app.dXEditField_2Label.Text = 'dX';
            app.dXEditField_2 = uieditfield(app.INPUTTab, 'numeric');
            app.dYEditField_2Label = uilabel(app.INPUTTab);
            app.dYEditField_2Label.Text = 'dY';
            app.dYEditField_2 = uieditfield(app.INPUTTab, 'numeric');
            app.dZEditField_2Label = uilabel(app.INPUTTab);
            app.dZEditField_2Label.Text = 'dZ';
            app.dZEditField_2 = uieditfield(app.INPUTTab, 'numeric');
            app.GrippercommandEditField_2Label = uilabel(app.INPUTTab);
            app.GrippercommandEditField_2Label.Text = 'Gripper command';
            app.GrippercommandEditField_2 = uieditfield(app.INPUTTab, 'numeric');
            app.ROSInputLabel = uilabel(app.INPUTTab);
            app.ROSInputLabel.Text = 'ROS Input';
            app.RosinputEditFieldLabel = uilabel(app.INPUTTab);
            app.RosinputEditFieldLabel.Text = 'Ros input';
            app.RosinputEditField = uieditfield(app.INPUTTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TGRui_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end