classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        VisualizationPanel     matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        SubscriptionPanel      matlab.ui.container.Panel
        UITable2_2             matlab.ui.control.Table
        UITable_4              matlab.ui.control.Table
        UITable_3              matlab.ui.control.Table
        PT2EditField           matlab.ui.control.EditField
        PT2EditFieldLabel      matlab.ui.control.Label
        UITable_2              matlab.ui.control.Table
        UITable2               matlab.ui.control.Table
        IMUEditField           matlab.ui.control.EditField
        IMUEditFieldLabel      matlab.ui.control.Label
        PT1Table               matlab.ui.control.Table
        PT1EditField           matlab.ui.control.EditField
        PT1EditFieldLabel      matlab.ui.control.Label
        RobotStatusPanel       matlab.ui.container.Panel
        Joint5Lamp             matlab.ui.control.Lamp
        Joint5LampLabel        matlab.ui.control.Label
        Testj5Button           matlab.ui.control.StateButton
        Joint4Lamp             matlab.ui.control.Lamp
        Joint4LampLabel        matlab.ui.control.Label
        Joint3Lamp             matlab.ui.control.Lamp
        Joint3LampLabel        matlab.ui.control.Label
        Joint2Lamp             matlab.ui.control.Lamp
        Joint2LampLabel        matlab.ui.control.Label
        Testj4Button           matlab.ui.control.StateButton
        Testj3Button           matlab.ui.control.StateButton
        Testj2Button           matlab.ui.control.StateButton
        Joint1Lamp             matlab.ui.control.Lamp
        Joint1LampLabel        matlab.ui.control.Label
        Testj1Button           matlab.ui.control.StateButton
        ConnectionStatusPanel  matlab.ui.container.Panel
        ConnectionLamp         matlab.ui.control.Lamp
        ConnectionLampLabel    matlab.ui.control.Label
        ConnectButton          matlab.ui.control.StateButton
        MotorStatusPanel       matlab.ui.container.Panel
        Motor4Lamp             matlab.ui.control.Lamp
        Motor4LampLabel        matlab.ui.control.Label
        Testm4Button           matlab.ui.control.StateButton
        Motor3Lamp             matlab.ui.control.Lamp
        Motor3LampLabel        matlab.ui.control.Label
        Testm3Button           matlab.ui.control.StateButton
        Motor2Lamp             matlab.ui.control.Lamp
        Motor2LampLabel        matlab.ui.control.Label
        Testm2Button           matlab.ui.control.StateButton
        Testm1Button           matlab.ui.control.StateButton
        Motor1Lamp             matlab.ui.control.Lamp
        Motor1LampLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)

        % MQTT Elements %
        BrokerAddress = "tcp://broker.hivemq.com"
        BrokerTopicSub = "M5Stack"
        BrokerTopicPub = "TGR/Pp/cmd"
        mqClient %ClientName
        % MQTT Elements%

        % Poten value %
        pt1 %poten of thumb
        pt2
        pt3
        pt4
        pt5
        data %raw data

        Ax
        Ay
        Az

        Gx
        Gy
        Gz
    
        %robot element%
        Robot
        IkWeight =[1 1 1 1 1 1];
        

        % command %
        invalidWrite
        sixty
        % command%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectButton
        function ConnectButtonEnter(app, event)
             
            app.mqClient = mqttclient(app.BrokerAddress);
            subscribe(app.mqClient,app.BrokerTopicSub);
            while(true)
            app.data = read(app.mqClient);
            pause(0.5)
                if isempty(app.data)==false
                    app.pt1 = (app.data.Data);
                    app.pt1 = str2double(app.data.Data);
                    app.PT1Table.Data = app.pt1;
                    app.ConnectionLamp.Color = "green";
                else
                    app.ConnectionLamp.Color = "yellow";
                end
            end
   

        end

        % Value changed function: Testm1Button
        function Testm1ButtonPush(app, event)
            % if poten received send motor command
            if (app.pt1 > 0) && (app.pt1 < 360)
        
                app.mqttClient = mqttclient(app.BrokerAddress);
                app.mypub = subscribe(app.mqClient,app.BrokerTopicPub);
                app.sixty = write(mqttClient,"TGR/Pp/cmd","60");

            else
                app.mqttClient = mqttclient(app.BrokerAddress);
                publish(app.mqClient,app.brokertopicPub);
                app.invalidWrite = write (mqttClient,"TGR/Pp/cmd","Invalid input");
            end
            
        end

        % Value changed function: PT1EditField
        function PT1EditFieldValueChanged(app, event)
           
                if isempty(app.data)==false
                    app.PT1EditFiseld.Value = app.data.Topic;
                   
                else
                    app.PT1EditFiseld.Value = 'no topic';
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotorStatusPanel = uipanel(app.UIFigure);
            app.MotorStatusPanel.Title = 'Motor Status';
            app.Motor1LampLabel = uilabel(app.MotorStatusPanel);
            app.Motor1LampLabel.Text = 'Motor1';
            app.Motor1Lamp = uilamp(app.MotorStatusPanel);
            app.Testm1Button = uibutton(app.MotorStatusPanel, 'state');
            app.Testm1Button.ValueChangedFcn = createCallbackFcn(app, @Testm1ButtonPush, true);
            app.Testm1Button.Text = 'Test m1';
            app.Testm2Button = uibutton(app.MotorStatusPanel, 'state');
            app.Testm2Button.Text = 'Test m2';
            app.Motor2LampLabel = uilabel(app.MotorStatusPanel);
            app.Motor2LampLabel.Text = 'Motor2';
            app.Motor2Lamp = uilamp(app.MotorStatusPanel);
            app.Testm3Button = uibutton(app.MotorStatusPanel, 'state');
            app.Testm3Button.Text = 'Test m3';
            app.Motor3LampLabel = uilabel(app.MotorStatusPanel);
            app.Motor3LampLabel.Text = 'Motor3';
            app.Motor3Lamp = uilamp(app.MotorStatusPanel);
            app.Testm4Button = uibutton(app.MotorStatusPanel, 'state');
            app.Testm4Button.Text = 'Test m4';
            app.Motor4LampLabel = uilabel(app.MotorStatusPanel);
            app.Motor4LampLabel.Text = 'Motor4';
            app.Motor4Lamp = uilamp(app.MotorStatusPanel);
            app.ConnectionStatusPanel = uipanel(app.UIFigure);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.ConnectButton = uibutton(app.ConnectionStatusPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonEnter, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLampLabel = uilabel(app.ConnectionStatusPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.ConnectionStatusPanel);
            app.RobotStatusPanel = uipanel(app.UIFigure);
            app.RobotStatusPanel.Title = 'Robot Status';
            app.Testj1Button = uibutton(app.RobotStatusPanel, 'state');
            app.Testj1Button.Text = 'Test j1';
            app.Joint1LampLabel = uilabel(app.RobotStatusPanel);
            app.Joint1LampLabel.Text = 'Joint1';
            app.Joint1Lamp = uilamp(app.RobotStatusPanel);
            app.Testj2Button = uibutton(app.RobotStatusPanel, 'state');
            app.Testj2Button.Text = 'Test j2';
            app.Testj3Button = uibutton(app.RobotStatusPanel, 'state');
            app.Testj3Button.Text = 'Test j3';
            app.Testj4Button = uibutton(app.RobotStatusPanel, 'state');
            app.Testj4Button.Text = 'Test j4';
            app.Joint2LampLabel = uilabel(app.RobotStatusPanel);
            app.Joint2LampLabel.Text = 'Joint2';
            app.Joint2Lamp = uilamp(app.RobotStatusPanel);
            app.Joint3LampLabel = uilabel(app.RobotStatusPanel);
            app.Joint3LampLabel.Text = 'Joint3';
            app.Joint3Lamp = uilamp(app.RobotStatusPanel);
            app.Joint4LampLabel = uilabel(app.RobotStatusPanel);
            app.Joint4LampLabel.Text = 'Joint4';
            app.Joint4Lamp = uilamp(app.RobotStatusPanel);
            app.Testj5Button = uibutton(app.RobotStatusPanel, 'state');
            app.Testj5Button.Text = 'Test j5';
            app.Joint5LampLabel = uilabel(app.RobotStatusPanel);
            app.Joint5LampLabel.Text = 'Joint5';
            app.Joint5Lamp = uilamp(app.RobotStatusPanel);
            app.SubscriptionPanel = uipanel(app.UIFigure);
            app.SubscriptionPanel.Title = 'Subscription';
            app.PT1EditFieldLabel = uilabel(app.SubscriptionPanel);
            app.PT1EditFieldLabel.Text = 'PT1';
            app.PT1EditField = uieditfield(app.SubscriptionPanel, 'text');
            app.PT1EditField.ValueChangedFcn = createCallbackFcn(app, @PT1EditFieldValueChanged, true);
            app.PT1EditField.Value = '10';
            app.PT1Table = uitable(app.SubscriptionPanel);
            app.PT1Table.ColumnName = {'PT1'};
            app.PT1Table.RowName = {};
            app.IMUEditFieldLabel = uilabel(app.SubscriptionPanel);
            app.IMUEditFieldLabel.Text = 'IMU';
            app.IMUEditField = uieditfield(app.SubscriptionPanel, 'text');
            app.UITable2 = uitable(app.SubscriptionPanel);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable2.RowName = {};
            app.UITable_2 = uitable(app.SubscriptionPanel);
            app.UITable_2.ColumnName = {'PT2'};
            app.UITable_2.RowName = {};
            app.PT2EditFieldLabel = uilabel(app.SubscriptionPanel);
            app.PT2EditFieldLabel.Text = 'PT2';
            app.PT2EditField = uieditfield(app.SubscriptionPanel, 'text');
            app.UITable_3 = uitable(app.SubscriptionPanel);
            app.UITable_3.ColumnName = {'PT3'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.SubscriptionPanel);
            app.UITable_4.ColumnName = {'PT4'};
            app.UITable_4.RowName = {};
            app.UITable2_2 = uitable(app.SubscriptionPanel);
            app.UITable2_2.ColumnName = {'Yaw'; 'Pitch'; 'Roll'};
            app.UITable2_2.RowName = {};
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end