classdef tgrSimpleController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SettingTab                matlab.ui.container.Tab
        Part3EditField            matlab.ui.control.EditField
        Part3EditFieldLabel       matlab.ui.control.Label
        Part2EditField            matlab.ui.control.EditField
        Part2EditFieldLabel       matlab.ui.control.Label
        Part1EditField            matlab.ui.control.EditField
        Part1Label                matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        HOMEButton                matlab.ui.control.Button
        MQTTTab                   matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        ControlTab                matlab.ui.container.Tab
        ZButton_Neg               matlab.ui.control.Button
        ZButton_Pos               matlab.ui.control.Button
        RobotZposEditField        matlab.ui.control.NumericEditField
        RobotZposEditFieldLabel   matlab.ui.control.Label
        RobotYposEditField        matlab.ui.control.NumericEditField
        RobotYposEditFieldLabel   matlab.ui.control.Label
        RobotXposEditField        matlab.ui.control.NumericEditField
        RobotXposEditFieldLabel   matlab.ui.control.Label
        YButton_Neg               matlab.ui.control.Button
        YButton_Pos               matlab.ui.control.Button
        XButton_Pos               matlab.ui.control.Button
        XButton_Neg               matlab.ui.control.Button
        MQTTPanel                 matlab.ui.container.Panel
        ConnectionLamp            matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        MQTTbrokerEditField       matlab.ui.control.EditField
        MQTTbrokerEditFieldLabel  matlab.ui.control.Label
        banner_bottom             matlab.ui.control.Image
        banner_top                matlab.ui.control.Image
    end

    
    properties (Access = private)
         BrokerAddress = "tcp://broker.hivemq.com"
        BrokerTopicSub = "tgr2022/bmeranger12/getting_position"
        BrokerTopicPub = "TGR/Pp/cmd"
        mqClient %ClientName
        % MQTT Elements%
        mqttClient      % MQTT client
        mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        data
        xpo
        ypo
        zpo
        result
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.ConnectionLamp.Color = 'r';
            app.MQTTbrokerEditField.Value = "tcp://broker.hivemq.com";
            % initialize robot setting
            app.Part1EditField.Value = "[0.4 -0.1 0.26]";
            app.Part2EditField.Value = "[0.5 -0.15 0.26]"; 
            app.Part3EditField.Value = "[0.52 0 0.26]";
            app.HOMEButton.Enable = "off";
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.mqClient = mqttclient(app.BrokerAddress);
            subscribe(app.mqClient,app.BrokerTopicSub);
            while(true)
                app.data = read(app.mqClient);
                pause(0.5)
                if isempty(app.data)==false
                    app.result = jsondecode(app.data.Data);
                    app.xpo = double(app.result.x);
                    app.ypo = double(app.result.y);
                    app.zpo = double(app.result.z);
                    app.UITable.Data(:,1) = app.xpo;
                    app.UITable.Data(:,2) = app.ypo;
                    app.UITable.Data(:,3) = app.zpo;
                    app.ConnectionLamp.Color = "green";
                else
                    app.ConnectionLamp.Color = "yellow";
                end
            end
            
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = str2num(app.Part1EditField.Value);
            part2 = str2num(app.Part2EditField.Value);
            part3 = str2num(app.Part3EditField.Value);
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                app.HOMEButton.Enable = "on";
            catch
                disp("Error from part setting");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.banner_top = uiimage(app.UIFigure);
            app.banner_top.ImageSource = fullfile(pathToMLAPP, 'm.jpg');
            app.banner_bottom = uiimage(app.UIFigure);
            app.banner_bottom.ImageSource = fullfile(pathToMLAPP, 'banner_bottom.jpg');
            app.MQTTPanel = uipanel(app.UIFigure);
            app.MQTTPanel.Title = 'MQTT';
            app.MQTTbrokerEditFieldLabel = uilabel(app.MQTTPanel);
            app.MQTTbrokerEditFieldLabel.Text = 'MQTT broker';
            app.MQTTbrokerEditField = uieditfield(app.MQTTPanel, 'text');
            app.ConnectButton = uibutton(app.MQTTPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.MQTTPanel);
            app.StatusLampLabel.Text = 'Status';
            app.ConnectionLamp = uilamp(app.MQTTPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.HOMEButton = uibutton(app.SettingTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.STARTButton = uibutton(app.SettingTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Part1Label = uilabel(app.SettingTab);
            app.Part1Label.Text = 'Part 1';
            app.Part1EditField = uieditfield(app.SettingTab, 'text');
            app.Part1EditField.Value = '[0.4 -0.1 0.26]';
            app.Part2EditFieldLabel = uilabel(app.SettingTab);
            app.Part2EditFieldLabel.Text = 'Part 2';
            app.Part2EditField = uieditfield(app.SettingTab, 'text');
            app.Part2EditField.Value = '[0.5 -0.15 0.26]';
            app.Part3EditFieldLabel = uilabel(app.SettingTab);
            app.Part3EditFieldLabel.Text = 'Part 3';
            app.Part3EditField = uieditfield(app.SettingTab, 'text');
            app.Part3EditField.Value = '[0.52 0 0.26]';
            app.MQTTTab = uitab(app.TabGroup);
            app.MQTTTab.Title = 'MQTT';
            app.UITable = uitable(app.MQTTTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.XButton_Neg = uibutton(app.ControlTab, 'push');
            app.XButton_Neg.Text = 'X-';
            app.XButton_Pos = uibutton(app.ControlTab, 'push');
            app.XButton_Pos.Text = 'X+';
            app.YButton_Pos = uibutton(app.ControlTab, 'push');
            app.YButton_Pos.Text = 'Y+';
            app.YButton_Neg = uibutton(app.ControlTab, 'push');
            app.YButton_Neg.Text = 'Y-';
            app.RobotXposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotXposEditFieldLabel.Text = 'Robot X pos';
            app.RobotXposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotYposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotYposEditFieldLabel.Text = 'Robot Y pos';
            app.RobotYposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotZposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotZposEditFieldLabel.Text = 'Robot Z pos';
            app.RobotZposEditField = uieditfield(app.ControlTab, 'numeric');
            app.ZButton_Pos = uibutton(app.ControlTab, 'push');
            app.ZButton_Pos.Text = 'Z+';
            app.ZButton_Neg = uibutton(app.ControlTab, 'push');
            app.ZButton_Neg.Text = 'Z-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tgrSimpleController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end