classdef tgrSimpleController2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SettingTab                matlab.ui.container.Tab
        Part3EditField            matlab.ui.control.EditField
        Part3EditFieldLabel       matlab.ui.control.Label
        Part2EditField            matlab.ui.control.EditField
        Part2EditFieldLabel       matlab.ui.control.Label
        Part1EditField            matlab.ui.control.EditField
        Part1Label                matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        HOMEButton                matlab.ui.control.Button
        MQTTTab                   matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        ControlTab                matlab.ui.container.Tab
        DistanceEditField         matlab.ui.control.NumericEditField
        DistanceEditFieldLabel    matlab.ui.control.Label
        Lamp3                     matlab.ui.control.Lamp
        Lamp3Label                matlab.ui.control.Label
        Lamp2                     matlab.ui.control.Lamp
        Lamp2Label                matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        LampLabel                 matlab.ui.control.Label
        ZButton_Neg               matlab.ui.control.Button
        ZButton_Pos               matlab.ui.control.Button
        RobotZposEditField        matlab.ui.control.NumericEditField
        RobotZposEditFieldLabel   matlab.ui.control.Label
        RobotYposEditField        matlab.ui.control.NumericEditField
        RobotYposEditFieldLabel   matlab.ui.control.Label
        RobotXposEditField        matlab.ui.control.NumericEditField
        RobotXposEditFieldLabel   matlab.ui.control.Label
        YButton_Neg               matlab.ui.control.Button
        YButton_Pos               matlab.ui.control.Button
        XButton_Pos               matlab.ui.control.Button
        XButton_Neg               matlab.ui.control.Button
        MQTTPanel                 matlab.ui.container.Panel
        ConnectionLamp            matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        MQTTbrokerEditField       matlab.ui.control.EditField
        MQTTbrokerEditFieldLabel  matlab.ui.control.Label
        banner_bottom             matlab.ui.control.Image
        banner_top                matlab.ui.control.Image
    end

    
    properties (Access = private)
        mqttClient      % MQTT client
        mqttCmdSub      % MQTT command
        robotCell       % robot-environment-interaction
        invKinematics   % inverse kinematic solver
        BrokerAddress = "tcp://broker.hivemq.com"
        BrokerTopicSub = "tgr2022/bmeranger12/getting_position"
        BrokerTopicPub = "TGR/Pp/cmd"
        mqClient %ClientName
        % MQTT Elements%
   
        
        data
        xpo
        ypo
        zpo
        result


        %robot part
        Robot
        
        dist1
        dist2
        dist3

        
        FinalPose%
        weight
        ik
        init_pose
        joint_sol

        xFinal
        yFinal
        zFinal

        curr

        grasp_pose
    end
    
    methods (Access = private)
                
        function dis1(app) 
            app.curr =  [app.RobotXposEditField.Value,app.RobotYposEditField.Value , app.RobotZposEditField.Value] ;%TODO
            app.dist1 = norm(app.curr -[0.4 -0.1 0.26]);
            if  app.dist1 > 0.1
                app.Lamp.Color = 'r';
               
            else 
                app.Lamp.Color = 'g';
               
            end

        end
        
        function dis2(app)
            app.curr =  [app.RobotXposEditField.Value,app.RobotYposEditField.Value , app.RobotZposEditField.Value] ;%TODO
            app.dist2 = norm(app.curr -[0.5 -0.15 0.26]);
            if  app.dist2 > 0.1
                app.Lamp2.Color = 'r';
               
            else 
                app.Lamp2.Color = 'g';
               
            end
            
        end
        
        function dis3(app)
            app.curr =  [app.RobotXposEditField.Value,app.RobotYposEditField.Value , app.RobotZposEditField.Value] ;%TODO
            app.dist3 = norm(app.curr -[0.52 0 0.26]);
            if  app.dist3 > 0.1
                app.Lamp3.Color = 'r';
               
            else 
                app.Lamp3.Color = 'g';
               
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize MQTT
            app.ConnectionLamp.Color = 'r';
            app.MQTTbrokerEditField.Value = "tcp://broker.hivemq.com";
            % initialize robot setting
            app.Part1EditField.Value = "[0.4 -0.1 0.26]";
            app.Part2EditField.Value = "[0.5 -0.15 0.26]"; 
            app.Part3EditField.Value = "[0.52 0 0.26]";
            app.HOMEButton.Enable = "off";
            %app.displayLamp() ;
           dis1(app);
           dis2(app);
           dis3(app);

            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
             app.mqClient = mqttclient(app.BrokerAddress);
            subscribe(app.mqClient,app.BrokerTopicSub);
            while(true)
                app.data = read(app.mqClient);
                pause(0.5)
                if isempty(app.data)==false
                    app.result = jsondecode(app.data.Data);
                    app.xpo = double(app.result.x);
                    app.ypo = double(app.result.y);
                    app.zpo = double(app.result.z);
                    app.UITable.Data(:,1) = app.xpo;
                    app.UITable.Data(:,2) = app.ypo;
                    app.UITable.Data(:,3) = app.zpo;
                    app.ConnectionLamp.Color = "green";
                else
                    app.ConnectionLamp.Color = "yellow";
                end
            end
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
             app.RobotXposEditField.Value =app.robotCell.HomeRobotTaskConfig(1,4);
            app.RobotYposEditField.Value =app.robotCell.HomeRobotTaskConfig(2,4);
             app.RobotZposEditField.Value =app.robotCell.HomeRobotTaskConfig(3,4);

%             app.grasp_pose(3,4) = trvec2tform(app.robotCell.Environment.Parts{1}.centerPoint.*axang2tform([0 1 0 pi])); 
%             app.DistanceEditField.Value = app.RobotZposEditField.Value - app.grasp_pose;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            robot = loadRobotSetting('KINOVAGen3GripperColl.mat'); 
            joint_states = homeConfiguration(robot);
            part1 = str2num(app.Part1EditField.Value);
            part2 = str2num(app.Part2EditField.Value);
            part3 = str2num(app.Part3EditField.Value);
            parts = {part1, part2, part3};
            colors = ["r", "g", "b"];
            try
                env = tgrCreateEnv(parts, colors);
                app.robotCell = tgrRobotEnvInteraction(robot, joint_states, "gripper", env);
                app.robotCell.visualizeStation();
                app.robotCell.visualizeParts();
                app.HOMEButton.Enable = "on";
            catch
                disp("Error from part setting");
            end
        end

        % Value changed function: RobotXposEditField
        function RobotXposEditFieldValueChanged(app, event)
%             app.XposEditField.Value = app.robotCell.HomeRobotTaskConfig(1,4);
%             app.FinalPose = app.robotCell.CurrentJointConfig;
%             app.xFinal = app.FinalPose(3,4);
%             app.xFinal = app.RobotXposEditField.Value;
        end

        % Value changed function: RobotYposEditField
        function RobotYposEditFieldValueChanged(app, event)
        app.YposEditField.Value = app.robotCell.HomeRobotTaskConfig(2,4);
% %             app.FinalPose = app.robotCell.CurrentJointConfig;
% %             app.FinalPose = app.robotCell.CurrentJointConfig;
%             app.yFinal = app.FinalPose(3,4);
%             app.yFinal = app.RobotXposEditField.Value;
            
        end

        % Button pushed function: ZButton_Neg
        function ZButton_NegPushed(app, event)
             
            app.RobotZposEditField.Value =  app.RobotZposEditField.Value-0.05;
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5-0.05])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
        end

        % Button pushed function: ZButton_Pos
        function ZButton_PosPushed(app, event)
            app.RobotZposEditField.Value =  app.RobotZposEditField.Value+0.05;
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0, 0.5+0.05])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            app.init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, app.init_pose);
            app.robotCell.moveJoints(joint_sols);
        end

        % Button pushed function: YButton_Neg
        function YButton_NegPushed(app, event)
           
            app.RobotYposEditField.Value =  app.RobotYposEditField.Value-0.05;
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0-0.05, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            app.init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, app.init_pose);
            app.robotCell.moveJoints(joint_sols);
        end

        % Button pushed function: YButton_Pos
        function YButton_PosPushed(app, event)
            app.RobotYposEditField.Value =  app.RobotYposEditField.Value+0.05;
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4, 0+0.05, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            app.init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, app.init_pose);
            app.robotCell.moveJoints(joint_sols);
        end

        % Button pushed function: XButton_Pos
        function XButton_PosPushed(app, event)
            app.RobotXposEditField.Value =  app.RobotXposEditField.Value+0.05;
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4+0.05, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);
        end

        % Button pushed function: XButton_Neg
        function XButton_NegPushed(app, event)
            app.RobotXposEditField.Value =  app.RobotXposEditField.Value-0.05;
            app.robotCell.HomeRobotTaskConfig = trvec2tform([0.4-0.05, 0, 0.5])*axang2tform([0 1 0 pi]);
            app.invKinematics = inverseKinematics('RigidBodyTree', app.robotCell.Robot);
            weights = ones(1, 6);
            init_pose = app.robotCell.CurrentJointConfig;
            joint_sols = app.invKinematics(app.robotCell.RobotEndEffector, app.robotCell.HomeRobotTaskConfig, weights, init_pose);
            app.robotCell.moveJoints(joint_sols);,
        end

        % Value changed function: RobotZposEditField
        function RobotZposEditFieldValueChanged(app, event)
       
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.banner_top = uiimage(app.UIFigure);
            app.banner_top.ImageSource = 'banner_top.jpg';
            app.banner_bottom = uiimage(app.UIFigure);
            app.banner_bottom.ImageSource = fullfile(pathToMLAPP, 'banner_bottom.jpg');
            app.MQTTPanel = uipanel(app.UIFigure);
            app.MQTTPanel.Title = 'MQTT';
            app.MQTTbrokerEditFieldLabel = uilabel(app.MQTTPanel);
            app.MQTTbrokerEditFieldLabel.Text = 'MQTT broker';
            app.MQTTbrokerEditField = uieditfield(app.MQTTPanel, 'text');
            app.ConnectButton = uibutton(app.MQTTPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.MQTTPanel);
            app.StatusLampLabel.Text = 'Status';
            app.ConnectionLamp = uilamp(app.MQTTPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.HOMEButton = uibutton(app.SettingTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.STARTButton = uibutton(app.SettingTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Part1Label = uilabel(app.SettingTab);
            app.Part1Label.Text = 'Part 1';
            app.Part1EditField = uieditfield(app.SettingTab, 'text');
            app.Part1EditField.Value = '[0.4 -0.1 0.26]';
            app.Part2EditFieldLabel = uilabel(app.SettingTab);
            app.Part2EditFieldLabel.Text = 'Part 2';
            app.Part2EditField = uieditfield(app.SettingTab, 'text');
            app.Part2EditField.Value = '[0.5 -0.15 0.26]';
            app.Part3EditFieldLabel = uilabel(app.SettingTab);
            app.Part3EditFieldLabel.Text = 'Part 3';
            app.Part3EditField = uieditfield(app.SettingTab, 'text');
            app.Part3EditField.Value = '[0.52 0 0.26]';
            app.MQTTTab = uitab(app.TabGroup);
            app.MQTTTab.Title = 'MQTT';
            app.UITable = uitable(app.MQTTTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.XButton_Neg = uibutton(app.ControlTab, 'push');
            app.XButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @XButton_NegPushed, true);
            app.XButton_Neg.Text = 'X-';
            app.XButton_Pos = uibutton(app.ControlTab, 'push');
            app.XButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @XButton_PosPushed, true);
            app.XButton_Pos.Text = 'X+';
            app.YButton_Pos = uibutton(app.ControlTab, 'push');
            app.YButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @YButton_PosPushed, true);
            app.YButton_Pos.Text = 'Y+';
            app.YButton_Neg = uibutton(app.ControlTab, 'push');
            app.YButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @YButton_NegPushed, true);
            app.YButton_Neg.Text = 'Y-';
            app.RobotXposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotXposEditFieldLabel.Text = 'Robot X pos';
            app.RobotXposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotXposEditField.ValueChangedFcn = createCallbackFcn(app, @RobotXposEditFieldValueChanged, true);
            app.RobotYposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotYposEditFieldLabel.Text = 'Robot Y pos';
            app.RobotYposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotYposEditField.ValueChangedFcn = createCallbackFcn(app, @RobotYposEditFieldValueChanged, true);
            app.RobotZposEditFieldLabel = uilabel(app.ControlTab);
            app.RobotZposEditFieldLabel.Text = 'Robot Z pos';
            app.RobotZposEditField = uieditfield(app.ControlTab, 'numeric');
            app.RobotZposEditField.ValueChangedFcn = createCallbackFcn(app, @RobotZposEditFieldValueChanged, true);
            app.ZButton_Pos = uibutton(app.ControlTab, 'push');
            app.ZButton_Pos.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PosPushed, true);
            app.ZButton_Pos.Text = 'Z+';
            app.ZButton_Neg = uibutton(app.ControlTab, 'push');
            app.ZButton_Neg.ButtonPushedFcn = createCallbackFcn(app, @ZButton_NegPushed, true);
            app.ZButton_Neg.Text = 'Z-';
            app.LampLabel = uilabel(app.ControlTab);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.ControlTab);
            app.Lamp2Label = uilabel(app.ControlTab);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.ControlTab);
            app.Lamp3Label = uilabel(app.ControlTab);
            app.Lamp3Label.Text = 'Lamp3';
            app.Lamp3 = uilamp(app.ControlTab);
            app.DistanceEditFieldLabel = uilabel(app.ControlTab);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.ControlTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tgrSimpleController2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end